classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        LocalisationdessourcesdmittionPanel  matlab.ui.container.Panel
        Image2                   matlab.ui.control.Image
        DtctiondessignauxPanel   matlab.ui.container.Panel
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        GnrerlasimulationButton  matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GnrerlasimulationButton
        function GnrerlasimulationButtonValueChanged(app, event)
            value = app.GnrerlasimulationButton.Value;
            [test_signal, window_length, noverlap,fe,~,~, ~,~,~,~, spect_binarise] = simul();
            if value ==1
                cla(app.UIAxes_3);
                [s,f,t,~] = spectrogram(test_signal, window_length, noverlap, [], fe, 'yaxis');
                imagesc(app.UIAxes_3, t, f, log(1+abs(s)));
                ylim(app.UIAxes_3,[0 2.5e7])
                set(app.UIAxes_3,'YDir', 'normal');

                cla(app.UIAxes_4);
                imagesc(app.UIAxes_4, t, f, spect_binarise);
                colormap(app.UIAxes_4,"gray")
                ylim(app.UIAxes_4,[0 2.5e7])
                set(app.UIAxes_4,'YDir', 'normal') 
                   
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GnrerlasimulationButton = uibutton(app.UIFigure, 'state');
            app.GnrerlasimulationButton.ValueChangedFcn = createCallbackFcn(app, @GnrerlasimulationButtonValueChanged, true);
            app.GnrerlasimulationButton.Text = 'Générer la simulation';
            app.DtctiondessignauxPanel = uipanel(app.UIFigure);
            app.DtctiondessignauxPanel.Title = 'Détéction des signaux ';
            app.UIAxes_3 = uiaxes(app.DtctiondessignauxPanel);
            title(app.UIAxes_3, 'Spectrogamme')
            xlabel(app.UIAxes_3, 'Temps')
            ylabel(app.UIAxes_3, 'Fréquence')
            zlabel(app.UIAxes_3, 'Z')
            colormap(app.UIAxes_3, 'parula')
            app.UIAxes_4 = uiaxes(app.DtctiondessignauxPanel);
            title(app.UIAxes_4, 'Spectrogamme')
            xlabel(app.UIAxes_4, 'Temps')
            ylabel(app.UIAxes_4, 'Fréquence')
            zlabel(app.UIAxes_4, 'Z')
            colormap(app.UIAxes_4, 'parula')
            app.LocalisationdessourcesdmittionPanel = uipanel(app.UIFigure);
            app.LocalisationdessourcesdmittionPanel.Title = 'Localisation des sources d''émittion';
            app.Image2 = uiimage(app.LocalisationdessourcesdmittionPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'loc.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Poster - S8.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end