classdef generateur_de_stimuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup3                      matlab.ui.container.TabGroup
        OndeimpulsionTab               matlab.ui.container.Tab
        ImulsionPanel                  matlab.ui.container.Panel
        ImpulsionCheckBox              matlab.ui.control.CheckBox
        UIAxes2_23                     matlab.ui.control.UIAxes
        OndecontinueTab                matlab.ui.container.Tab
        GFSKGPSKPanel_2                matlab.ui.container.Panel
        CWCheckBox                     matlab.ui.control.CheckBox
        FMCWCheckBox                   matlab.ui.control.CheckBox
        UIAxes2_22                     matlab.ui.control.UIAxes
        UIAxes2_21                     matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        MonoporteusespectrenontalTab   matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        CommunicationsnumriquesTab     matlab.ui.container.Tab
        GFSKGPSKPanel                  matlab.ui.container.Panel
        GFSKCheckBox                   matlab.ui.control.CheckBox
        GPSKCheckBox                   matlab.ui.control.CheckBox
        UIAxes2_12                     matlab.ui.control.UIAxes
        UIAxes2_11                     matlab.ui.control.UIAxes
        ASKAPKPSKQAMPanel              matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        PSKCheckBox                    matlab.ui.control.CheckBox
        QAMCheckBox                    matlab.ui.control.CheckBox
        ASKCheckBox                    matlab.ui.control.CheckBox
        APKCheckBox                    matlab.ui.control.CheckBox
        UIAxes2_10                     matlab.ui.control.UIAxes
        UIAxes2_9                      matlab.ui.control.UIAxes
        UIAxes2_8                      matlab.ui.control.UIAxes
        UIAxes2_7                      matlab.ui.control.UIAxes
        CommunicationsanalogiquesTab   matlab.ui.container.Tab
        AMFMPMPanel                    matlab.ui.container.Panel
        PMCheckBox                     matlab.ui.control.CheckBox
        AMCheckBox_2                   matlab.ui.control.CheckBox
        FMCheckBox_2                   matlab.ui.control.CheckBox
        UIAxes2_15                     matlab.ui.control.UIAxes
        UIAxes2_13                     matlab.ui.control.UIAxes
        UIAxes2_14                     matlab.ui.control.UIAxes
        MonoporteusespectretalTab      matlab.ui.container.Tab
        DHSSFHSSCSSPanel               matlab.ui.container.Panel
        CSSCheckBox_3                  matlab.ui.control.CheckBox
        DSSSCheckBox_3                 matlab.ui.control.CheckBox
        FHSSCheckBox_3                 matlab.ui.control.CheckBox
        UIAxes2_18                     matlab.ui.control.UIAxes
        UIAxes2_17                     matlab.ui.control.UIAxes
        UIAxes2_16                     matlab.ui.control.UIAxes
        MultiporteusespectrenontalTab  matlab.ui.container.Tab
        OFDMPanel                      matlab.ui.container.Panel
        OFDMCheckBox                   matlab.ui.control.CheckBox
        UIAxes2_20                     matlab.ui.control.UIAxes
        SignauxdecommunicationsLabel   matlab.ui.control.Label
        GnrerlessignauxButton          matlab.ui.control.StateButton
        SignauxRadarLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        x1 
        x2
        ti1
        tf1
        te
        ti2
        tf2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CWCheckBoxValueChanged(app, event)
            
            
        end

        % Callback function
        function FMCWCheckBoxValueChanged(app, event)

        end

        % Value changed function: CWCheckBox
        function CWCheckBoxValueChanged2(app, event)
            value = app.CWCheckBox.Value;
            if value == 1
                % Demander les paramètres à l'utilisateur
                prompt = {'Temps d''échantillonnage (s):','Temps de début (s):','Temps de fin (s):'};
                dlgtitle = 'Paramètres du signal';
                dims = [1 50];
                defaultinput = {'4e-11','2e-5','6e-5'};
                params = inputdlg(prompt,dlgtitle,dims,defaultinput);
                
                % Convertir les entrées en nombres
                sample_time = str2double(params{1});
                start_time = str2double(params{2});
                end_time = str2double(params{3});
                
                % Vérifier que les entrées sont valides
                if isnan(sample_time) || isnan(start_time) || isnan(end_time) || ...
                        sample_time <= 0 || start_time < 0 || end_time <= start_time
                    errordlg('Entrées invalides. Veuillez entrer des nombres positifs.','Erreur');
                else
                    % Tracer la figure avec les paramètres saisis
                    [y,t] = CW(start_time,end_time, sample_time) ;
                    app.x1 = y;
                    app.ti1 = start_time;
                    app.tf1 = end_time;
                    app.te = sample_time;
                    cla(app.UIAxes2_21); % Effacer l'axe avant de tracer
                    plot(app.UIAxes2_21,t,y);
                    xlim(app.UIAxes2_21, [0 10e-8]);
                    xlabel(app.UIAxes2_21,'Temps (s)');
                    ylabel(app.UIAxes2_21,'Amplitude');
                    title(app.UIAxes2_21,'Allure temporelle du signal CW');
                    app.UIAxes2_21.XGrid = 'on';
                    app.UIAxes2_21.YGrid = 'on';
                end
            end
            
        end

        % Value changed function: ASKCheckBox
        function ASKCheckBoxValueChanged(app, event)

        end

        % Value changed function: AMCheckBox_2
        function AMCheckBox_2ValueChanged(app, event)
            value = app.AMCheckBox_2.Value;
            if value == 1
                % Demander les paramètres à l'utilisateur
            prompt = {'Fréquence du signal modulant :','Taux de modulation :','Amplitude porteuse :', 'Fréquence porteuse :', 'Ratio de dépassement'};
                dlgtitle = 'Paramètres du signal';
                dims = [1 50];
                defaultinput = {'1e3','0.5','1','1e4','4'};
                params = inputdlg(prompt,dlgtitle,dims,defaultinput);
                
                % Convertir les entrées en nombres
                Fm = str2double(params{1});
                k = str2double(params{2});
                Ap = str2double(params{3});
                Fp = str2double(params{4});
                OSR = str2double(params{5});

                % Tracer la figure avec les paramètres saisis
                %Modulation
                Am=1;
                N_2=10000;
                Fmax=10*10^3;
                Fs=Fmax*2^OSR;
                Ts=1/Fs;
                t= (1:N_2)*Ts;
                m=Am*cos(2*pi*Fm*t);
                %Porteuse
                p=Ap*cos(2*pi*Fp*t);
                s = onde_AM(m,Fm,k,p,Ap,Fp,OSR,'DBAP');
                %cla(app.UIAxes2_13); % Effacer l'axe avant de tracer
                plot(app.UIAxes2_13,t,s);
                xlim(app.UIAxes2_13, [0 3/Fm]);
                xlabel(app.UIAxes2_13,'Temps (s)');
                ylabel(app.UIAxes2_13,'Amplitude');
                title(app.UIAxes2_13,'Allure temporelle du signal AM');
                app.UIAxes2_13.XGrid = 'on';
                app.UIAxes2_13.YGrid = 'on';
            
            end

        end

        % Value changed function: FMCWCheckBox
        function FMCWCheckBoxValueChanged2(app, event)
            value = app.FMCWCheckBox.Value;
            if value == 1
                % Demander les paramètres à l'utilisateur
                prompt = {'Temps d''échantillonnage (s):','Temps de début (s):','Temps de fin (s):'};
                dlgtitle = 'Paramètres du signal';
                dims = [1 50];
                defaultinput = {'4e-11','4e-5','8e-5'};
                params = inputdlg(prompt,dlgtitle,dims,defaultinput);
                
                % Convertir les entrées en nombres
                sample_time = str2double(params{1});
                start_time = str2double(params{2});
                end_time = str2double(params{3});
                
                % Vérifier que les entrées sont valides
                if isnan(sample_time) || isnan(start_time) || isnan(end_time) || ...
                        sample_time <= 0 || start_time < 0 || end_time <= start_time
                    errordlg('Entrées invalides. Veuillez entrer des nombres positifs.','Erreur');
                else
                    % Tracer la figure avec les paramètres saisis
                    f = 10; % Fréquence initiale en Hz
                    B = 20+50e6; % Bande passante en Hz
                    [y,t] = FMCW(start_time,end_time, sample_time, B, f) ;
                    app.x2 = y;
                    app.ti2 = start_time;
                    app.tf2 = end_time;
                    app.te = sample_time;
                    %cla(app.UIAxes2_22); % Effacer l'axe avant de tracer
                    plot(app.UIAxes2_22,t,y);
                    xlim(app.UIAxes2_22, [0 3e-6]);
                    xlabel(app.UIAxes2_22,'Temps (s)');
                    ylabel(app.UIAxes2_22,'Amplitude');
                    app.UIAxes2_22.XGrid = 'on';
                    app.UIAxes2_22.YGrid = 'on';
                end
            end
            
        end

        % Value changed function: PMCheckBox
        function PMCheckBoxValueChanged(app, event)
            value = app.PMCheckBox.Value;
            if value == 1
                % Demander les paramètres à l'utilisateur
                prompt = {'Fréquence du signal modulant :','Amplitude porteuse :', 'Fréquence porteuse :', 'Ratio de dépassement'};
                dlgtitle = 'Paramètres du signal';
                dims = [1 50];
                defaultinput = {'1e3','1','1e4','4'};
                params = inputdlg(prompt,dlgtitle,dims,defaultinput);
                
                % Convertir les entrées en nombres
                Fm = str2double(params{1});
                Ap = str2double(params{2});
                Fp = str2double(params{3});
                OSR = str2double(params{4});

                % Tracer la figure avec les paramètres saisis
                %Modulation
                Am=1;
                N_2=10000;
                Fmax=10*10^3;
                Fs=Fmax*2^OSR;
                Ts=1/Fs;
                t= (1:N_2)*Ts;
                m=Am*cos(2*pi*Fm*t);
                %Porteuse
                p=Ap*cos(2*pi*Fp*t);
                s = onde_PM(m,Fm,Am,p,Ap,Fp,OSR,5);
                %cla(app.UIAxes2_13); % Effacer l'axe avant de tracer
                plot(app.UIAxes2_15,t,s);
                xlim(app.UIAxes2_15, [0 3/Fm]);
                xlabel(app.UIAxes2_15,'Temps (s)');
                ylabel(app.UIAxes2_15,'Amplitude');
                title(app.UIAxes2_15,'Allure temporelle du signal PM');
                app.UIAxes2_15.XGrid = 'on';
                app.UIAxes2_15.YGrid = 'on';
           
            end

            
        end

        % Value changed function: FMCheckBox_2
        function FMCheckBox_2ValueChanged(app, event)
            value = app.FMCheckBox_2.Value;
            if value == 1
                % Demander les paramètres à l'utilisateur
            prompt = {'Fréquence du signal modulant :','Taux de modulation :','Amplitude porteuse :', 'Fréquence porteuse :', 'Ratio de dépassement'};
                dlgtitle = 'Paramètres du signal';
                dims = [1 50];
                defaultinput = {'1e3','0.5','1','1e4','4'};
                params = inputdlg(prompt,dlgtitle,dims,defaultinput);
                
                % Convertir les entrées en nombres
                Fm = str2double(params{1});
                k = str2double(params{2});
                Ap = str2double(params{3});
                Fp = str2double(params{4});
                OSR = str2double(params{5});

                % Tracer la figure avec les paramètres saisis
                %Modulation
                Am=1;
                N_2=10000;
                Fmax=10*10^3;
                Fs=Fmax*2^OSR;
                Ts=1/Fs;
                t= (1:N_2)*Ts;
                m=Am*cos(2*pi*Fm*t);
                %Porteuse
                p=Ap*cos(2*pi*Fp*t);
                s = onde_FM(m,Am,Fm,k,p,Ap,Fp,OSR);
                %cla(app.UIAxes2_13); % Effacer l'axe avant de tracer
                plot(app.UIAxes2_14,t,s);
                xlim(app.UIAxes2_14, [0 3/Fm]);
                xlabel(app.UIAxes2_14,'Temps (s)');
                ylabel(app.UIAxes2_14,'Amplitude');
                title(app.UIAxes2_14,'Allure temporelle du signal FM');
                app.UIAxes2_14.XGrid = 'on';
                app.UIAxes2_14.YGrid = 'on';
            
            end
        end

        % Value changed function: ImpulsionCheckBox
        function ImpulsionCheckBoxValueChanged(app, event)
            value = app.ImpulsionCheckBox.Value;
            
            if value == 1
%                 % Demander les paramètres à l'utilisateur
%                 prompt = {'Fréquence du signal modulant :','Taux de modulation :','Amplitude porteuse :', 'Fréquence porteuse :', 'Ratio de dépassement'};
%                 dlgtitle = 'Paramètres du signal';
%                 dims = [1 50];
%                 defaultinput = {'1e3','0.5','1','1e4','4'};
%                 params = inputdlg(prompt,dlgtitle,dims,defaultinput);
%                 
%                 % Convertir les entrées en nombres
%                 Fm = str2double(params{1});
%                 k = str2double(params{2});
%                 Ap = str2double(params{3});
%                 Fp = str2double(params{4});
%                 OSR = str2double(params{5});
                Fp = 1e4;
                OSR = 4;
                s = radar_pulse(100,10000,Fp,OSR,5);
                %cla(app.UIAxes2_13); % Effacer l'axe avant de tracer
                plot(app.UIAxes2_23,s);
                %xlim(app.UIAxes2_23, [0 3/Fm]);
                xlabel(app.UIAxes2_23,'Temps (s)');
                ylabel(app.UIAxes2_23,'Amplitude');
                title(app.UIAxes2_23,'Allure temporelle du signal');
                app.UIAxes2_23.XGrid = 'on';
                app.UIAxes2_23.YGrid = 'on';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignauxRadarLabel = uilabel(app.UIFigure);
            app.SignauxRadarLabel.Text = 'Signaux Radar';
            app.GnrerlessignauxButton = uibutton(app.UIFigure, 'state');
            app.GnrerlessignauxButton.Text = 'Générer les signaux ';
            app.SignauxdecommunicationsLabel = uilabel(app.UIFigure);
            app.SignauxdecommunicationsLabel.Text = 'Signaux de communications';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MonoporteusespectrenontalTab = uitab(app.TabGroup);
            app.MonoporteusespectrenontalTab.Title = 'Mono porteuse spectre non étalé';
            app.TabGroup2 = uitabgroup(app.MonoporteusespectrenontalTab);
            app.CommunicationsnumriquesTab = uitab(app.TabGroup2);
            app.CommunicationsnumriquesTab.Title = 'Communications numériques';
            app.ASKAPKPSKQAMPanel = uipanel(app.CommunicationsnumriquesTab);
            app.ASKAPKPSKQAMPanel.Title = 'ASK - APK - PSK - QAM';
            app.UIAxes2_7 = uiaxes(app.ASKAPKPSKQAMPanel);
            title(app.UIAxes2_7, 'Allure temporelle du signal ASK')
            xlabel(app.UIAxes2_7, 'Temps (s)')
            ylabel(app.UIAxes2_7, 'Amplitude')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes2_8 = uiaxes(app.ASKAPKPSKQAMPanel);
            title(app.UIAxes2_8, 'Allure temporelle du signal PSK')
            xlabel(app.UIAxes2_8, 'Temps (s)')
            ylabel(app.UIAxes2_8, 'Amplitude')
            zlabel(app.UIAxes2_8, 'Z')
            app.UIAxes2_9 = uiaxes(app.ASKAPKPSKQAMPanel);
            title(app.UIAxes2_9, 'Allure temporelle du signal APK')
            xlabel(app.UIAxes2_9, 'Temps (s)')
            ylabel(app.UIAxes2_9, 'Amplitude')
            zlabel(app.UIAxes2_9, 'Z')
            app.UIAxes2_10 = uiaxes(app.ASKAPKPSKQAMPanel);
            title(app.UIAxes2_10, 'Allure temporelle du signal QAM')
            xlabel(app.UIAxes2_10, 'Temps (s)')
            ylabel(app.UIAxes2_10, 'Amplitude')
            zlabel(app.UIAxes2_10, 'Z')
            app.APKCheckBox = uicheckbox(app.ASKAPKPSKQAMPanel);
            app.APKCheckBox.Text = 'APK';
            app.ASKCheckBox = uicheckbox(app.ASKAPKPSKQAMPanel);
            app.ASKCheckBox.ValueChangedFcn = createCallbackFcn(app, @ASKCheckBoxValueChanged, true);
            app.ASKCheckBox.Text = 'ASK';
            app.QAMCheckBox = uicheckbox(app.ASKAPKPSKQAMPanel);
            app.QAMCheckBox.Text = 'QAM';
            app.PSKCheckBox = uicheckbox(app.ASKAPKPSKQAMPanel);
            app.PSKCheckBox.Text = 'PSK';
            app.UITable = uitable(app.ASKAPKPSKQAMPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GFSKGPSKPanel = uipanel(app.CommunicationsnumriquesTab);
            app.GFSKGPSKPanel.Title = 'GFSK - GPSK';
            app.UIAxes2_11 = uiaxes(app.GFSKGPSKPanel);
            title(app.UIAxes2_11, 'Allure temporelle du signal GFSK')
            xlabel(app.UIAxes2_11, 'Temps (s)')
            ylabel(app.UIAxes2_11, 'Amplitude')
            zlabel(app.UIAxes2_11, 'Z')
            app.UIAxes2_12 = uiaxes(app.GFSKGPSKPanel);
            title(app.UIAxes2_12, 'Allure temporelle du signal GMSK')
            xlabel(app.UIAxes2_12, 'Temps (s)')
            ylabel(app.UIAxes2_12, 'Amplitude')
            zlabel(app.UIAxes2_12, 'Z')
            app.GPSKCheckBox = uicheckbox(app.GFSKGPSKPanel);
            app.GPSKCheckBox.Text = 'GMSK';
            app.GFSKCheckBox = uicheckbox(app.GFSKGPSKPanel);
            app.GFSKCheckBox.Text = 'GFSK';
            app.CommunicationsanalogiquesTab = uitab(app.TabGroup2);
            app.CommunicationsanalogiquesTab.Title = 'Communications analogiques';
            app.AMFMPMPanel = uipanel(app.CommunicationsanalogiquesTab);
            app.AMFMPMPanel.Title = 'AM - FM - PM';
            app.UIAxes2_14 = uiaxes(app.AMFMPMPanel);
            title(app.UIAxes2_14, 'Allure temporelle du signal FM')
            xlabel(app.UIAxes2_14, 'Temps (s)')
            ylabel(app.UIAxes2_14, 'Amplitude')
            zlabel(app.UIAxes2_14, 'Z')
            app.UIAxes2_13 = uiaxes(app.AMFMPMPanel);
            title(app.UIAxes2_13, 'Allure temporelle du signal AM')
            xlabel(app.UIAxes2_13, 'Temps (s)')
            ylabel(app.UIAxes2_13, 'Amplitude')
            zlabel(app.UIAxes2_13, 'Z')
            app.UIAxes2_15 = uiaxes(app.AMFMPMPanel);
            title(app.UIAxes2_15, 'Allure temporelle du signal PM')
            xlabel(app.UIAxes2_15, 'Temps (s)')
            ylabel(app.UIAxes2_15, 'Amplitude')
            zlabel(app.UIAxes2_15, 'Z')
            app.FMCheckBox_2 = uicheckbox(app.AMFMPMPanel);
            app.FMCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FMCheckBox_2ValueChanged, true);
            app.FMCheckBox_2.Text = 'FM';
            app.AMCheckBox_2 = uicheckbox(app.AMFMPMPanel);
            app.AMCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AMCheckBox_2ValueChanged, true);
            app.AMCheckBox_2.Text = 'AM';
            app.PMCheckBox = uicheckbox(app.AMFMPMPanel);
            app.PMCheckBox.ValueChangedFcn = createCallbackFcn(app, @PMCheckBoxValueChanged, true);
            app.PMCheckBox.Text = 'PM';
            app.MonoporteusespectretalTab = uitab(app.TabGroup);
            app.MonoporteusespectretalTab.Title = 'Mono porteuse spectre étalé';
            app.DHSSFHSSCSSPanel = uipanel(app.MonoporteusespectretalTab);
            app.DHSSFHSSCSSPanel.Title = 'DHSS - FHSS - CSS';
            app.UIAxes2_16 = uiaxes(app.DHSSFHSSCSSPanel);
            title(app.UIAxes2_16, 'Allure temporelle du signal FHSS')
            xlabel(app.UIAxes2_16, 'Temps (s)')
            ylabel(app.UIAxes2_16, 'Amplitude')
            zlabel(app.UIAxes2_16, 'Z')
            app.UIAxes2_17 = uiaxes(app.DHSSFHSSCSSPanel);
            title(app.UIAxes2_17, 'Allure temporelle du signal DSSS')
            xlabel(app.UIAxes2_17, 'Temps (s)')
            ylabel(app.UIAxes2_17, 'Amplitude')
            zlabel(app.UIAxes2_17, 'Z')
            app.UIAxes2_18 = uiaxes(app.DHSSFHSSCSSPanel);
            title(app.UIAxes2_18, 'Allure temporelle du signal CSS')
            xlabel(app.UIAxes2_18, 'Temps (s)')
            ylabel(app.UIAxes2_18, 'Amplitude')
            zlabel(app.UIAxes2_18, 'Z')
            app.FHSSCheckBox_3 = uicheckbox(app.DHSSFHSSCSSPanel);
            app.FHSSCheckBox_3.Text = 'FHSS';
            app.DSSSCheckBox_3 = uicheckbox(app.DHSSFHSSCSSPanel);
            app.DSSSCheckBox_3.Text = 'DSSS';
            app.CSSCheckBox_3 = uicheckbox(app.DHSSFHSSCSSPanel);
            app.CSSCheckBox_3.Text = 'CSS';
            app.MultiporteusespectrenontalTab = uitab(app.TabGroup);
            app.MultiporteusespectrenontalTab.Title = 'Multi porteuse spectre non étalé';
            app.OFDMPanel = uipanel(app.MultiporteusespectrenontalTab);
            app.OFDMPanel.Title = 'OFDM';
            app.UIAxes2_20 = uiaxes(app.OFDMPanel);
            title(app.UIAxes2_20, 'Allure temporelle du signal OFDM')
            xlabel(app.UIAxes2_20, 'Temps (s)')
            ylabel(app.UIAxes2_20, 'Amplitude')
            zlabel(app.UIAxes2_20, 'Z')
            app.OFDMCheckBox = uicheckbox(app.OFDMPanel);
            app.OFDMCheckBox.Text = 'OFDM';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.OndeimpulsionTab = uitab(app.TabGroup3);
            app.OndeimpulsionTab.Title = 'Onde à impulsion';
            app.ImulsionPanel = uipanel(app.OndeimpulsionTab);
            app.ImulsionPanel.Title = 'Imulsion';
            app.UIAxes2_23 = uiaxes(app.ImulsionPanel);
            title(app.UIAxes2_23, 'Allure temporelle')
            xlabel(app.UIAxes2_23, 'Temps (s)')
            ylabel(app.UIAxes2_23, 'Amplitude')
            zlabel(app.UIAxes2_23, 'Z')
            app.ImpulsionCheckBox = uicheckbox(app.ImulsionPanel);
            app.ImpulsionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImpulsionCheckBoxValueChanged, true);
            app.ImpulsionCheckBox.Text = 'Impulsion';
            app.OndecontinueTab = uitab(app.TabGroup3);
            app.OndecontinueTab.Title = 'Onde continue';
            app.GFSKGPSKPanel_2 = uipanel(app.OndecontinueTab);
            app.GFSKGPSKPanel_2.Title = 'GFSK - GPSK';
            app.UIAxes2_21 = uiaxes(app.GFSKGPSKPanel_2);
            title(app.UIAxes2_21, 'Allure temporelle du signal CW')
            xlabel(app.UIAxes2_21, 'Temps (s)')
            ylabel(app.UIAxes2_21, 'Amplitude')
            zlabel(app.UIAxes2_21, 'Z')
            app.UIAxes2_22 = uiaxes(app.GFSKGPSKPanel_2);
            title(app.UIAxes2_22, 'Allure temporelle du signal FMCW')
            xlabel(app.UIAxes2_22, 'Temps (s)')
            ylabel(app.UIAxes2_22, 'Amplitude')
            zlabel(app.UIAxes2_22, 'Z')
            app.FMCWCheckBox = uicheckbox(app.GFSKGPSKPanel_2);
            app.FMCWCheckBox.ValueChangedFcn = createCallbackFcn(app, @FMCWCheckBoxValueChanged2, true);
            app.FMCWCheckBox.Text = 'FMCW';
            app.CWCheckBox = uicheckbox(app.GFSKGPSKPanel_2);
            app.CWCheckBox.ValueChangedFcn = createCallbackFcn(app, @CWCheckBoxValueChanged2, true);
            app.CWCheckBox.Text = 'CW';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generateur_de_stimuli

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end